classdef App_Mental_Workload < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        elapsedtimeEditField          matlab.ui.control.NumericEditField
        elapsedtimeEditFieldLabel     matlab.ui.control.Label
        StopButton                    matlab.ui.control.StateButton
        TARindexPanel                 matlab.ui.container.Panel
        TARindexdecreasedLamp         matlab.ui.control.Lamp
        TARindexdecreasedLampLabel    matlab.ui.control.Label
        TARindexincreasedLamp         matlab.ui.control.Lamp
        TARindexincreasedLampLabel    matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        AlphaindexPanel               matlab.ui.container.Panel
        AlphaindexdecreasedLamp       matlab.ui.control.Lamp
        AlphaindexdecreasedLampLabel  matlab.ui.control.Label
        AlphaindexincreasedLamp       matlab.ui.control.Lamp
        AlphaindexincreasedLampLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        BetaindexPanel                matlab.ui.container.Panel
        BetaindexdecreasedLamp        matlab.ui.control.Lamp
        BetaindexdecreasedLampLabel   matlab.ui.control.Label
        BetaindexincreasedLamp        matlab.ui.control.Lamp
        BetaindexincreasedLampLabel   matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        EEGchannelsPanel              matlab.ui.container.Panel
        R4CheckBox                    matlab.ui.control.CheckBox
        R3CheckBox                    matlab.ui.control.CheckBox
        R2CheckBox                    matlab.ui.control.CheckBox
        R1CheckBox                    matlab.ui.control.CheckBox
        C4CheckBox                    matlab.ui.control.CheckBox
        CzCheckBox                    matlab.ui.control.CheckBox
        C3CheckBox                    matlab.ui.control.CheckBox
        L4CheckBox                    matlab.ui.control.CheckBox
        L3CheckBox                    matlab.ui.control.CheckBox
        L2CheckBox                    matlab.ui.control.CheckBox
        L1CheckBox                    matlab.ui.control.CheckBox
        t_maxEditField                matlab.ui.control.EditField
        t_maxEditFieldLabel           matlab.ui.control.Label
        ThetaindexPanel               matlab.ui.container.Panel
        ThetaindexdecreasedLamp       matlab.ui.control.Lamp
        ThetaindexdecreasedLampLabel  matlab.ui.control.Label
        ThetaindexincreasedLamp       matlab.ui.control.Lamp
        ThetaindexincreasedLampLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        fsEditField                   matlab.ui.control.EditField
        fsEditFieldLabel              matlab.ui.control.Label
        calibrationwindowsEditField   matlab.ui.control.EditField
        calibrationwindowsEditFieldLabel  matlab.ui.control.Label
        windowEditField               matlab.ui.control.EditField
        windowEditFieldLabel          matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
   
        EEG;
        EEG_temp=zeros(11,1);
        indikatori=cell(1,4);
        i=1;
        fs;
        ts;
        x;
        st=0;
        b=1;
        CH_test;
        niz=ones(1,11);
        indexes_abs=[];
        indexes_rel=[];
        stp;
        Agent;
        BW_Agent;
        T = timer(  'ExecutionMode','singleShot',... %{singleShot,fixedRate,fixedSpacing,fixedDelay}
                    'BusyMode','drop',... %{drop, error, queue}
                    'TasksToExecute',inf,...          
                    'StartDelay',0,...
                    'TimerFcn',[],...
                    'StartFcn',[],...
                    'StopFcn',[],...
                    'ErrorFcn',[]);
         
    end


    methods (Access = private)


    end
    
    methods (Access = public)
        
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Agent = py.importlib.import_module('eegSendToFiware');
            app.BW_Agent=app.Agent.PythonRequest();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
       
            app.stp=0;
            app.EEG=cell(4,11,1);
            app.b=1;
            t=str2num(app.windowEditField.Value);
            f=str2num(app.fsEditField.Value);
            t_max=str2num(app.t_maxEditField.Value);
            app.fs=f;
            app.ts=t;
            app.x=app.ts*app.fs;
            N_max=60*t_max*app.fs;
            w=N_max/app.x;
            
            i_cal1= cell(1,4);
            p_values1 = cell(1,4);
            outliers1 = cell(1,4);
            svi_indeksi1 = cell(1,4);
            i_for_outliers1 =cell(1,4);
            outliers_all1 = cell(1,4);
            i_test1=cell(1,4);
            prvi1=cell(1,4);
            
            out_val =5;
            lower_val =-10;
            higher_val=10;
            calib_val=2.5;
            THR=0.05;
            CALIB_SAMPS = str2num(app.calibrationwindowsEditField.Value);
            
            lib = lsl_loadlib();
            result = {};
            while isempty(result)
                result = lsl_resolve_byprop(lib,'type','EEG');
            end
            inlet = lsl_inlet(result{1});
            
            
            set(app.T, 'TimerFcn',@timer_function);
            set(app.T, 'StopFcn',@stop_function);
            start(app.T);
             
            function timer_function(obj, event)
                tic
                app.i = 1; 
                app.EEG_temp=zeros(11,1);
                app.elapsedtimeEditField.Value=app.b*t;
                  while size(app.EEG_temp,2)<app.x  
                     [vec,ts] = inlet.pull_sample();
%                      vec=rand(1,11);
                     app.EEG_temp(:,app.i) = vec';
                     app.i = app.i+1;
                  end
                  
                toc
            end
            
            function stop_function(obj, event)
                   
                
                if app.stp==1 || app.b==w
                    stop(app.T);
                    disp('STOP')
                    
                    app.ThetaindexincreasedLamp.Enable='off';
                    app.ThetaindexdecreasedLamp.Enable='off';
                    app.AlphaindexincreasedLamp.Enable='off';
                    app.AlphaindexdecreasedLamp.Enable='off';
                    app.BetaindexincreasedLamp.Enable='off';
                    app.BetaindexdecreasedLamp.Enable='off';
                    app.TARindexincreasedLamp.Enable='off';
                    app.TARindexdecreasedLamp.Enable='off';
                    
                    mat=app.EEG;
                    mat1=app.indikatori;
                    
                    time = datestr(now, 'yyyymmddTHHMMSS');
                    [filename, pathname] = uiputfile('*.mat','Save Workspace Variables As');
                    filename1=erase(filename,".mat");
                    filename2=append(filename1,'_',time,'.mat');
                    newfilename = fullfile(pathname,filename2);
                    save(newfilename,'mat')
                    
                    [filename_1, pathname_1] = uiputfile('*.mat','Save Workspace Variables As');
                    filename_11=erase(filename_1,".mat");
                    filename_12=append(filename_11,'_',time,'.mat');
                    newfilename_1 = fullfile(pathname_1,filename_12);
                    save(newfilename_1,'mat1')
                    
                else
                
                for j=1:11
                            app.EEG{1,j}(app.b,1:app.x)=app.EEG_temp(j,1:app.x);
                end
                
                
                signal_temp=app.EEG_temp;

                [Welch_temp,fw] = pwelch(signal_temp',[],[],[],app.fs);
                PSD_welch = Welch_temp';

                BANDS = [4,7; 8,12; 15 30];

                POWER_rel_temp = [];
                POWER_abs_mean = [];
                POWER_rel_f = [];
                POWER_rela_f = [];

                for I2 = 1:size(BANDS,1)
                    [~,BANDS_index(I2,1)] =min(abs(fw-BANDS(I2,1))); 
                    [~,BANDS_index(I2,2)] =min(abs(fw-BANDS(I2,2)));

                    for I3 = 1: size(PSD_welch,1)
                        pow_temp_rel = sum(PSD_welch(I3, BANDS_index(I2,1): BANDS_index(I2,2))); 
                        pow_temp_mean = mean(PSD_welch(I3, BANDS_index(I2,1): BANDS_index(I2,2))); 
    
                        POWER_rel_temp(I3,I2) =  pow_temp_rel ; 
                        POWER_abs_mean(I3,I2) =  pow_temp_mean ;     
                    end

                end

                for ch=1:size( POWER_rel_temp,1)
                    for l = 1:size(POWER_rel_temp,2)
                        POWER_rel_f(ch,l) = POWER_rel_temp(ch,l)/(sum(POWER_rel_temp(ch,:)))*100 ;  
                        POWER_rela_f(ch,l) = POWER_abs_mean(ch,l)/(sum(POWER_abs_mean(ch,:)))*100 ;   
                    end
                end

                for I4 = 1:size(BANDS,1)   
                    app.indexes_abs(I4,app.b) = POWER_abs_mean(app.CH_test,I4);
                    app.indexes_rel(I4,app.b) = POWER_rela_f(app.CH_test,I4);
                end
                    app.indexes_abs(4,app.b)=POWER_abs_mean(app.CH_test,1)/POWER_abs_mean(app.CH_test,2);


                for ind=1:size(app.indexes_abs,1)
                    
                    i_tr = app.indexes_abs(ind,app.b);
                    
                    if(app.b==1)
                        i_cal= [];
                        prvi = 1;
                        p_values = [];
                        outliers = [];
                        svi_indeksi = [];
                        i_for_outliers = [];
                        outliers_all = [];
                        i_test=[];
                    else 
                    i_cal= i_cal1{1,ind};
                    prvi = prvi1{1,ind};
                    p_values = p_values1{1,ind};
                    outliers = outliers1{1,ind};
                    i_for_outliers = i_for_outliers1{1,ind};   
                    outliers_all = outliers_all1{1,ind};
                    i_test=i_test1{1,ind};
                    svi_indeksi = svi_indeksi1{1,ind};
                    end
                    
                    
                    III=0;
    
                        
                    i_for_outliers = [i_for_outliers, i_tr];
    
                    if length(i_for_outliers)==CALIB_SAMPS
                        TFa = isoutlier(i_for_outliers);
                        outliers_all = [outliers_all find(TFa)];     
                    end
                    if length(i_for_outliers)>CALIB_SAMPS
                        TFa = isoutlier(i_for_outliers);
                        if TFa(end)==1
                            outliers_all = [outliers_all app.b];
                        end
                    end
                   
   
                    if length(i_cal)<CALIB_SAMPS
        
                        III= calib_val; 

                        i_cal = [i_cal, i_tr];
        
                        if length(i_cal)==CALIB_SAMPS
                            TFc = [];
                            TFc = isoutlier(i_cal);
                            i_cal(TFc)=[]; 
                
                            if ~isempty(find(TFc))
                                outliers = [outliers find(TFc==1)];
                                svi_indeksi(find(TFc==1))=out_val;
                            end
                        end
            
           
                    end


                    if length(i_cal)==CALIB_SAMPS
                        if prvi==1
                            i_test = i_cal(2:end);
                            prvi=0;
                        end
                    end
   
    
                    if prvi==0 && length(i_test)<CALIB_SAMPS  
        
                        i_test = [i_test, i_tr]; 
        
                        if length(i_test)==CALIB_SAMPS 
                            if TFa(end)==1
                                i_test(end)=[]; 
                                outliers = [outliers app.b];
                                III = out_val;
                            end
                
                        if length(i_test)==CALIB_SAMPS   
                            [p,h] = ranksum(i_test,i_cal);
                            p_values = [p_values, p];
                                if p<THR
                                    if median(i_test)>median(i_cal)
                                        III=higher_val;
                                    end
                                if median(i_test)<median(i_cal)
                                    III=lower_val;
                                end
                                end
                    
                    
                        i_test(1)=[];
                        end

                        end

                    end
    
                    svi_indeksi = [svi_indeksi, III];
                    
                    app.indikatori{1,ind}=svi_indeksi;
                    
                    i_cal1{1,ind}=i_cal;
                    prvi1{1,ind} = prvi;
                    p_values1{1,ind}= p_values;
                    outliers1{1,ind}= outliers;
                    svi_indeksi1{1,ind}=svi_indeksi;
                    i_for_outliers1{1,ind}=i_for_outliers;
                    outliers_all1{1,ind}=outliers_all;
                    i_test1{1,ind}=i_test;


                    i_b = find((svi_indeksi)==2.5);
                    i_o = find((svi_indeksi)==5);
                    i_d = find((svi_indeksi)==-10);
                    i_i = find((svi_indeksi)==10);
                    if isempty(i_b) i_b=1; end
                    if isempty(i_o) i_o=1; end
                    if isempty(i_d) i_d=1; end
                    if isempty(i_i) i_i=1; end


                    if(ind==1)
                      
                        plot(app.UIAxes,1:length(app.indexes_abs(ind,:)), app.indexes_abs(ind,:), i_b, app.indexes_abs(ind,i_b), '*y', i_o, app.indexes_abs(ind,i_o), '*b', i_d, app.indexes_abs(ind,i_d), '*g', i_i, app.indexes_abs(ind,i_i), '*r' ) 

                       %stem(app.UIAxes,1:length(svi_indeksi), svi_indeksi);
                        if(svi_indeksi(end)==10)
                            app.ThetaindexincreasedLamp.Enable='on';
                        elseif(svi_indeksi(end)==-10)
                            app.ThetaindexdecreasedLamp.Enable='on';
                        else
                            app.ThetaindexincreasedLamp.Enable='off';
                            app.ThetaindexdecreasedLamp.Enable='off';
                        end
                    elseif(ind==2)
                        plot(app.UIAxes2,1:length(app.indexes_abs(ind,:)), app.indexes_abs(ind,:), i_b, app.indexes_abs(ind,i_b), '*y', i_o, app.indexes_abs(ind,i_o), '*b', i_d, app.indexes_abs(ind,i_d), '*g', i_i, app.indexes_abs(ind,i_i), '*r' ) 
                     %   stem(app.UIAxes2,1:length(svi_indeksi), svi_indeksi);
                        if(svi_indeksi(end)==10)
                            app.AlphaindexincreasedLamp.Enable='on';
                        elseif(svi_indeksi(end)==-10)
                            app.AlphaindexdecreasedLamp.Enable='on';
                        else
                            app.AlphaindexincreasedLamp.Enable='off';
                            app.AlphaindexdecreasedLamp.Enable='off';
                        end
                    elseif(ind==3)
                        plot(app.UIAxes3,1:length(app.indexes_abs(ind,:)), app.indexes_abs(ind,:), i_b, app.indexes_abs(ind,i_b), '*y', i_o, app.indexes_abs(ind,i_o), '*b', i_d, app.indexes_abs(ind,i_d), '*g', i_i, app.indexes_abs(ind,i_i), '*r' )  
                    %    stem(app.UIAxes3,1:length(svi_indeksi), svi_indeksi);
                        if(svi_indeksi(end)==10)
                            app.BetaindexincreasedLamp.Enable='on';
                        elseif(svi_indeksi(end)==-10)
                            app.BetaindexdecreasedLamp.Enable='on';
                        else
                            app.BetaindexincreasedLamp.Enable='off';
                            app.BetaindexdecreasedLamp.Enable='off';
                        end
                    else
                        plot(app.UIAxes4,1:length(app.indexes_abs(ind,:)), app.indexes_abs(ind,:), i_b, app.indexes_abs(ind,i_b), '*y', i_o, app.indexes_abs(ind,i_o), '*b', i_d, app.indexes_abs(ind,i_d), '*g', i_i, app.indexes_abs(ind,i_i), '*r' )   
                     %   stem(app.UIAxes4,1:length(svi_indeksi), svi_indeksi);
                        if(svi_indeksi(end)==10)
                            app.TARindexincreasedLamp.Enable='on';
                        elseif(svi_indeksi(end)==-10)
                            app.TARindexdecreasedLamp.Enable='on';
                        else
                            app.TARindexincreasedLamp.Enable='off';
                            app.TARindexdecreasedLamp.Enable='off';
                        end
                    end
                    

                end
                
                app.b=app.b+1; 
                pause(0.01)
                %app.BW_Agent.publish('{"device-ctrl":{"data":"PERA5","dev-eui":"0000000000008000"},"output-ctrl":{"method":"POST","ipv6":"2a02:1810:3d1c:1000:0:0:0:0","thing-id":"urn:uuid:e4370d7c-eae3-416d-85f0-751d396cacf5"},"adapter-ctrl":{"from":"urn:uuid:489edd56-4b1c-4332-a5ba-cad14994668d"}}');
                app.BW_Agent.sendToFiware(svi_indeksi(end));
                start(app.T)
                
                end
            end


        end

        % Value changed function: L1CheckBox
        function L1CheckBoxValueChanged(app, event)
            value = app.L1CheckBox.Value;
            
            app.CH_test=1;
        end

        % Value changed function: L2CheckBox
        function L2CheckBoxValueChanged(app, event)
            value = app.L2CheckBox.Value;
            app.CH_test=2;
        end

        % Value changed function: L3CheckBox
        function L3CheckBoxValueChanged(app, event)
            value = app.L3CheckBox.Value;
            app.CH_test=3;
        end

        % Value changed function: L4CheckBox
        function L4CheckBoxValueChanged(app, event)
            value = app.L4CheckBox.Value;
            app.CH_test=4;
        end

        % Value changed function: C3CheckBox
        function C3CheckBoxValueChanged(app, event)
            value = app.C3CheckBox.Value;
            app.CH_test=9;
        end

        % Value changed function: R1CheckBox
        function R1CheckBoxValueChanged(app, event)
            value = app.R1CheckBox.Value;
            app.CH_test=5;
        end

        % Value changed function: R2CheckBox
        function R2CheckBoxValueChanged(app, event)
            value = app.R2CheckBox.Value;
            app.CH_test=6;
        end

        % Value changed function: R3CheckBox
        function R3CheckBoxValueChanged(app, event)
            value = app.R3CheckBox.Value;
            app.CH_test=7;
        end

        % Value changed function: R4CheckBox
        function R4CheckBoxValueChanged(app, event)
            value = app.R4CheckBox.Value;
            app.CH_test=8;
        end

        % Value changed function: CzCheckBox
        function CzCheckBoxValueChanged(app, event)
            value = app.CzCheckBox.Value;
            if(value)
            app.CH_test=10;
            end
        end

        % Value changed function: C4CheckBox
        function C4CheckBoxValueChanged(app, event)
            value = app.C4CheckBox.Value;
            app.CH_test=11;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            app.stp=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.windowEditFieldLabel = uilabel(app.UIFigure);
            app.windowEditFieldLabel.Text = 'window';
            app.windowEditField = uieditfield(app.UIFigure, 'text');
            app.calibrationwindowsEditFieldLabel = uilabel(app.UIFigure);
            app.calibrationwindowsEditFieldLabel.Text = {'calibration'; 'windows'; ''};
            app.calibrationwindowsEditField = uieditfield(app.UIFigure, 'text');
            app.fsEditFieldLabel = uilabel(app.UIFigure);
            app.fsEditFieldLabel.Text = {'fs'; ''};
            app.fsEditField = uieditfield(app.UIFigure, 'text');
            app.fsEditField.Value = '500';
            app.ThetaindexPanel = uipanel(app.UIFigure);
            app.ThetaindexPanel.Title = 'Theta index';
            app.UIAxes = uiaxes(app.ThetaindexPanel);
            title(app.UIAxes, 'Theta')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'index value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.ThetaindexincreasedLampLabel = uilabel(app.ThetaindexPanel);
            app.ThetaindexincreasedLampLabel.Text = 'Theta index increased';
            app.ThetaindexincreasedLamp = uilamp(app.ThetaindexPanel);
            app.ThetaindexincreasedLamp.Enable = 'off';
            app.ThetaindexdecreasedLampLabel = uilabel(app.ThetaindexPanel);
            app.ThetaindexdecreasedLampLabel.Text = 'Theta index decreased';
            app.ThetaindexdecreasedLamp = uilamp(app.ThetaindexPanel);
            app.ThetaindexdecreasedLamp.Enable = 'off';
            app.t_maxEditFieldLabel = uilabel(app.UIFigure);
            app.t_maxEditFieldLabel.Text = 't_max';
            app.t_maxEditField = uieditfield(app.UIFigure, 'text');
            app.EEGchannelsPanel = uipanel(app.UIFigure);
            app.EEGchannelsPanel.Title = 'EEG channels';
            app.L1CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.L1CheckBox.ValueChangedFcn = createCallbackFcn(app, @L1CheckBoxValueChanged, true);
            app.L1CheckBox.Text = 'L1';
            app.L2CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.L2CheckBox.ValueChangedFcn = createCallbackFcn(app, @L2CheckBoxValueChanged, true);
            app.L2CheckBox.Text = 'L2';
            app.L3CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.L3CheckBox.ValueChangedFcn = createCallbackFcn(app, @L3CheckBoxValueChanged, true);
            app.L3CheckBox.Text = 'L3';
            app.L4CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.L4CheckBox.ValueChangedFcn = createCallbackFcn(app, @L4CheckBoxValueChanged, true);
            app.L4CheckBox.Text = 'L4';
            app.C3CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @C3CheckBoxValueChanged, true);
            app.C3CheckBox.Text = 'C3';
            app.CzCheckBox = uicheckbox(app.EEGchannelsPanel);
            app.CzCheckBox.ValueChangedFcn = createCallbackFcn(app, @CzCheckBoxValueChanged, true);
            app.CzCheckBox.Text = 'Cz';
            app.C4CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @C4CheckBoxValueChanged, true);
            app.C4CheckBox.Text = 'C4';
            app.R1CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.R1CheckBox.ValueChangedFcn = createCallbackFcn(app, @R1CheckBoxValueChanged, true);
            app.R1CheckBox.Text = 'R1';
            app.R2CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.R2CheckBox.ValueChangedFcn = createCallbackFcn(app, @R2CheckBoxValueChanged, true);
            app.R2CheckBox.Text = 'R2';
            app.R3CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.R3CheckBox.ValueChangedFcn = createCallbackFcn(app, @R3CheckBoxValueChanged, true);
            app.R3CheckBox.Text = 'R3';
            app.R4CheckBox = uicheckbox(app.EEGchannelsPanel);
            app.R4CheckBox.ValueChangedFcn = createCallbackFcn(app, @R4CheckBoxValueChanged, true);
            app.R4CheckBox.Text = 'R4';
            app.BetaindexPanel = uipanel(app.UIFigure);
            app.BetaindexPanel.Title = 'Beta index';
            app.UIAxes3 = uiaxes(app.BetaindexPanel);
            title(app.UIAxes3, 'Beta')
            xlabel(app.UIAxes3, 'time [s]')
            ylabel(app.UIAxes3, 'index value')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.BetaindexincreasedLampLabel = uilabel(app.BetaindexPanel);
            app.BetaindexincreasedLampLabel.Text = 'Beta index increased';
            app.BetaindexincreasedLamp = uilamp(app.BetaindexPanel);
            app.BetaindexincreasedLamp.Enable = 'off';
            app.BetaindexdecreasedLampLabel = uilabel(app.BetaindexPanel);
            app.BetaindexdecreasedLampLabel.Text = 'Beta index decreased';
            app.BetaindexdecreasedLamp = uilamp(app.BetaindexPanel);
            app.BetaindexdecreasedLamp.Enable = 'off';
            app.AlphaindexPanel = uipanel(app.UIFigure);
            app.AlphaindexPanel.Title = 'Alpha index';
            app.UIAxes2 = uiaxes(app.AlphaindexPanel);
            title(app.UIAxes2, 'Alpha')
            xlabel(app.UIAxes2, 'time [s]')
            ylabel(app.UIAxes2, 'index value')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.AlphaindexincreasedLampLabel = uilabel(app.AlphaindexPanel);
            app.AlphaindexincreasedLampLabel.Text = 'Alpha index increased';
            app.AlphaindexincreasedLamp = uilamp(app.AlphaindexPanel);
            app.AlphaindexincreasedLamp.Enable = 'off';
            app.AlphaindexdecreasedLampLabel = uilabel(app.AlphaindexPanel);
            app.AlphaindexdecreasedLampLabel.Text = 'Alpha index decreased';
            app.AlphaindexdecreasedLamp = uilamp(app.AlphaindexPanel);
            app.AlphaindexdecreasedLamp.Enable = 'off';
            app.TARindexPanel = uipanel(app.UIFigure);
            app.TARindexPanel.Title = 'TAR index';
            app.UIAxes4 = uiaxes(app.TARindexPanel);
            title(app.UIAxes4, 'TAR')
            xlabel(app.UIAxes4, 'time [s]')
            ylabel(app.UIAxes4, 'index value')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.TARindexincreasedLampLabel = uilabel(app.TARindexPanel);
            app.TARindexincreasedLampLabel.Text = 'TAR index increased';
            app.TARindexincreasedLamp = uilamp(app.TARindexPanel);
            app.TARindexincreasedLamp.Enable = 'off';
            app.TARindexdecreasedLampLabel = uilabel(app.TARindexPanel);
            app.TARindexdecreasedLampLabel.Text = 'TAR index decreased';
            app.TARindexdecreasedLamp = uilamp(app.TARindexPanel);
            app.TARindexdecreasedLamp.Enable = 'off';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.elapsedtimeEditFieldLabel = uilabel(app.UIFigure);
            app.elapsedtimeEditFieldLabel.Text = 'elapsed time';
            app.elapsedtimeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Mental_Workload

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end